classdef tutorialApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PanelSpecification             matlab.ui.container.Panel
        PanelareamEditFieldLabel       matlab.ui.control.Label
        PanelareamEditField            matlab.ui.control.NumericEditField
        RatedPowerWEditFieldLabel      matlab.ui.control.Label
        RatedPowerWEditField           matlab.ui.control.NumericEditField
        RefIrradianceWmEditFieldLabel  matlab.ui.control.Label
        RefIrradianceWmEditField       matlab.ui.control.NumericEditField
        TempCoeff1CEditFieldLabel      matlab.ui.control.Label
        TempCoeff1CEditField           matlab.ui.control.NumericEditField
        ThermalResKmWLabel             matlab.ui.control.Label
        ThermalResKmWEditField         matlab.ui.control.NumericEditField
        AbsorptanceEditFieldLabel      matlab.ui.control.Label
        AbsorptanceEditField           matlab.ui.control.NumericEditField
        ThermalCapJKmEditFieldLabel    matlab.ui.control.Label
        ThermalCapJKmEditField         matlab.ui.control.NumericEditField
        I_sc_refAEditFieldLabel        matlab.ui.control.Label
        I_sc_refAEditField             matlab.ui.control.NumericEditField
        V_oc_refVEditFieldLabel        matlab.ui.control.Label
        V_oc_refVEditField             matlab.ui.control.NumericEditField
        EnvironmentalConditionsPanel   matlab.ui.container.Panel
        SeasonDropDownLabel            matlab.ui.control.Label
        SeasonDropDown                 matlab.ui.control.DropDown
        LocationDropDownLabel          matlab.ui.control.Label
        LocationDropDown               matlab.ui.control.DropDown
        WeatherDropDownLabel           matlab.ui.control.Label
        WeatherDropDown                matlab.ui.control.DropDown
        AnalyzeButton                  matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        InputSummaryPanel              matlab.ui.container.Panel
        TextArea                       matlab.ui.control.TextArea
        OutputButton                   matlab.ui.control.Button
    end

    methods (Access = private)

        % Value changed function: PanelareamEditField
        function PanelareamEditFieldValueChanged(app, event)
            value = app.PanelareamEditField.Value;
            
        end

        % Value changed function: RatedPowerWEditField
        function RatedPowerWEditFieldValueChanged(app, event)
            value = app.RatedPowerWEditField.Value;
            
        end

        % Value changed function: RefIrradianceWmEditField
        function RefIrradianceWmEditFieldValueChanged(app, event)
            value = app.RefIrradianceWmEditField.Value;
            
        end

        % Value changed function: TempCoeff1CEditField
        function TempCoeff1CEditFieldValueChanged(app, event)
            value = app.TempCoeff1CEditField.Value;
            
        end

        % Value changed function: ThermalResKmWEditField
        function ThermalResKmWEditFieldValueChanged(app, event)
            value = app.ThermalResKmWEditField.Value;
            
        end

        % Value changed function: ThermalCapJKmEditField
        function ThermalCapJKmEditFieldValueChanged(app, event)
            value = app.ThermalCapJKmEditField.Value;
            
        end

        % Value changed function: AbsorptanceEditField
        function AbsorptanceEditFieldValueChanged(app, event)
            value = app.AbsorptanceEditField.Value;
            
        end

        % Value changed function: SeasonDropDown
        function SeasonDropDownValueChanged(app, event)
            value = app.SeasonDropDown.Value;
            
        end

        % Value changed function: LocationDropDown
        function LocationDropDownValueChanged(app, event)
            value = app.LocationDropDown.Value;
            
        end

        % Value changed function: WeatherDropDown
        function WeatherDropDownValueChanged(app, event)
            value = app.WeatherDropDown.Value;
            
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            
            A = app.PanelareamEditField.Value;
            P_rated = app.RatedPowerWEditField.Value;          % Rated Power (W)
            G_ref = app.RefIrradianceWmEditField.Value;        % Reference Irradiance (W/m^2)
            beta = app.TempCoeff1CEditField.Value;             % Temperature coefficient (1/°C)
            R_th = app.ThermalResKmWEditField.Value;           % Thermal Resistance (K·m²/W)
            absorptance = app.AbsorptanceEditField.Value;      % Absorptance (dimensionless)
            C_th = app.ThermalCapJKmEditField.Value;           % Thermal Capacitance (J/K·m²)
            season = app.SeasonDropDown.Value;                 % Selected Season
            location = app.LocationDropDown.Value;             % Selected Location
            weather = app.WeatherDropDown.Value;
            V_oc_ref= app.V_oc_refVEditField.Value;
            I_sc_ref= app.I_sc_refAEditField.Value;
            
            assignin('base', 'A', app.PanelareamEditField.Value);
            assignin('base', 'P_rated', app.RatedPowerWEditField.Value);
            assignin('base', 'G_ref', app.RefIrradianceWmEditField.Value);
            assignin('base', 'beta', app.TempCoeff1CEditField.Value);
            assignin('base', 'R_th', app.ThermalResKmWEditField.Value);
            assignin('base', 'absorptance', app.AbsorptanceEditField.Value);
            assignin('base', 'C_th', app.ThermalCapJKmEditField.Value);
            assignin('base', 'season', app.SeasonDropDown.Value);
            assignin('base', 'location', app.LocationDropDown.Value);
            assignin('base', 'weather', app.WeatherDropDown.Value);
            assignin('base', 'V_oc_ref', app.V_oc_refVEditField.Value);
            assignin('base', 'I_sc_ref', app.I_sc_refAEditField.Value);

            app.TextArea.Value = {
                sprintf('Panel Area (A): %.2f m²', A);
                sprintf('Rated Power: %.2f W', P_rated);
                sprintf('Ref. Irradiance: %.2f W/m²', G_ref);
                sprintf('Temp Coeff (β): %.4f 1/°C', beta);
                sprintf('Thermal Resistance: %.2f K·m²/W', R_th);
                sprintf('Absorptance: %.2f', absorptance);
                sprintf('Thermal Capacitance: %.2f J/K·m²', C_th);
                ['Season: ', season];
                ['Location: ', location];
                ['Weather: ', weather];
                sprintf('V_oc_ref: %.4f',V_oc_ref );
                sprintf('I_sc_ref: %.2f J/K·m²', I_sc_ref);
                };
                
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.PanelareamEditField.Value = 0;
            app.RatedPowerWEditField.Value = 0;
            app.RefIrradianceWmEditField.Value = 1000; % Often 1000 W/m^2
            app.TempCoeff1CEditField.Value = 0;
            app.ThermalResKmWEditField.Value = 0;
            app.AbsorptanceEditField.Value = 0.9;      % Typical default
            app.ThermalCapJKmEditField.Value = 0;
        
            % Reset dropdowns to first option (or default)
            app.SeasonDropDown.Value = app.SeasonDropDown.Items{1};
            app.LocationDropDown.Value = app.LocationDropDown.Items{1};
            app.WeatherDropDown.Value = app.WeatherDropDown.Items{1};
        end

        % Button pushed function: OutputButton
        function OutputButtonPushed(app, event)
            delete(app)
        end

        % Value changed function: V_oc_refVEditField
        function V_oc_refVEditFieldValueChanged(app, event)
            value = app.V_oc_refVEditField.Value;
            
        end

        % Value changed function: I_sc_refAEditField
        function I_sc_refAEditFieldValueChanged(app, event)
            value = app.I_sc_refAEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.PanelSpecification = uipanel(app.UIFigure);
            app.PanelSpecification.Title = 'Panel Specification';
            app.PanelareamEditFieldLabel = uilabel(app.PanelSpecification);
            app.PanelareamEditFieldLabel.Text = 'Panel area (mÂ²)';
            app.PanelareamEditField = uieditfield(app.PanelSpecification, 'numeric');
            app.PanelareamEditField.ValueChangedFcn = createCallbackFcn(app, @PanelareamEditFieldValueChanged, true);
            app.RatedPowerWEditFieldLabel = uilabel(app.PanelSpecification);
            app.RatedPowerWEditFieldLabel.Text = 'Rated Power(W)';
            app.RatedPowerWEditField = uieditfield(app.PanelSpecification, 'numeric');
            app.RatedPowerWEditField.ValueChangedFcn = createCallbackFcn(app, @RatedPowerWEditFieldValueChanged, true);
            app.RefIrradianceWmEditFieldLabel = uilabel(app.PanelSpecification);
            app.RefIrradianceWmEditFieldLabel.Text = 'Ref. Irradiance (W/mÂ²)';
            app.RefIrradianceWmEditField = uieditfield(app.PanelSpecification, 'numeric');
            app.RefIrradianceWmEditField.ValueChangedFcn = createCallbackFcn(app, @RefIrradianceWmEditFieldValueChanged, true);
            app.TempCoeff1CEditFieldLabel = uilabel(app.PanelSpecification);
            app.TempCoeff1CEditFieldLabel.Text = 'Temp Coeff (1/Â°C)';
            app.TempCoeff1CEditField = uieditfield(app.PanelSpecification, 'numeric');
            app.TempCoeff1CEditField.ValueChangedFcn = createCallbackFcn(app, @TempCoeff1CEditFieldValueChanged, true);
            app.ThermalResKmWLabel = uilabel(app.PanelSpecification);
            app.ThermalResKmWLabel.Text = 'Thermal Res(KÂ·mÂ²/W)';
            app.ThermalResKmWEditField = uieditfield(app.PanelSpecification, 'numeric');
            app.ThermalResKmWEditField.ValueChangedFcn = createCallbackFcn(app, @ThermalResKmWEditFieldValueChanged, true);
            app.AbsorptanceEditFieldLabel = uilabel(app.PanelSpecification);
            app.AbsorptanceEditFieldLabel.Text = 'Absorptance';
            app.AbsorptanceEditField = uieditfield(app.PanelSpecification, 'numeric');
            app.AbsorptanceEditField.ValueChangedFcn = createCallbackFcn(app, @AbsorptanceEditFieldValueChanged, true);
            app.ThermalCapJKmEditFieldLabel = uilabel(app.PanelSpecification);
            app.ThermalCapJKmEditFieldLabel.Text = 'Thermal Cap (J/KÂ·mÂ²)';
            app.ThermalCapJKmEditField = uieditfield(app.PanelSpecification, 'numeric');
            app.ThermalCapJKmEditField.ValueChangedFcn = createCallbackFcn(app, @ThermalCapJKmEditFieldValueChanged, true);
            app.I_sc_refAEditFieldLabel = uilabel(app.PanelSpecification);
            app.I_sc_refAEditFieldLabel.Text = 'I_sc_ref (A)';
            app.I_sc_refAEditField = uieditfield(app.PanelSpecification, 'numeric');
            app.I_sc_refAEditField.ValueChangedFcn = createCallbackFcn(app, @I_sc_refAEditFieldValueChanged, true);
            app.V_oc_refVEditFieldLabel = uilabel(app.PanelSpecification);
            app.V_oc_refVEditFieldLabel.Text = 'V_oc_ref (V)';
            app.V_oc_refVEditField = uieditfield(app.PanelSpecification, 'numeric');
            app.V_oc_refVEditField.ValueChangedFcn = createCallbackFcn(app, @V_oc_refVEditFieldValueChanged, true);
            app.EnvironmentalConditionsPanel = uipanel(app.UIFigure);
            app.EnvironmentalConditionsPanel.Title = 'Environmental Conditions';
            app.SeasonDropDownLabel = uilabel(app.EnvironmentalConditionsPanel);
            app.SeasonDropDownLabel.Text = 'Season';
            app.SeasonDropDown = uidropdown(app.EnvironmentalConditionsPanel);
            app.SeasonDropDown.Items = {'Summer', 'Spring', 'Winter'};
            app.SeasonDropDown.ValueChangedFcn = createCallbackFcn(app, @SeasonDropDownValueChanged, true);
            app.SeasonDropDown.Value = 'Summer';
            app.LocationDropDownLabel = uilabel(app.EnvironmentalConditionsPanel);
            app.LocationDropDownLabel.Text = 'Location';
            app.LocationDropDown = uidropdown(app.EnvironmentalConditionsPanel);
            app.LocationDropDown.Items = {'Tropical', 'Temperate', 'Cold'};
            app.LocationDropDown.ValueChangedFcn = createCallbackFcn(app, @LocationDropDownValueChanged, true);
            app.LocationDropDown.Value = 'Tropical';
            app.WeatherDropDownLabel = uilabel(app.EnvironmentalConditionsPanel);
            app.WeatherDropDownLabel.Text = 'Weather';
            app.WeatherDropDown = uidropdown(app.EnvironmentalConditionsPanel);
            app.WeatherDropDown.Items = {'Clear Sky', 'Partly Cloudy', 'Overcast'};
            app.WeatherDropDown.ValueChangedFcn = createCallbackFcn(app, @WeatherDropDownValueChanged, true);
            app.WeatherDropDown.Value = 'Clear Sky';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.InputSummaryPanel = uipanel(app.UIFigure);
            app.InputSummaryPanel.Title = 'Input Summary';
            app.TextArea = uitextarea(app.InputSummaryPanel);
            app.OutputButton = uibutton(app.UIFigure, 'push');
            app.OutputButton.ButtonPushedFcn = createCallbackFcn(app, @OutputButtonPushed, true);
            app.OutputButton.Text = 'Output';
        end
    end

    methods (Access = public)

        % Construct app
        function app = tutorialApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end